classdef AnalysisSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisVideoSettingsUIFigure   matlab.ui.Figure
        TraceFilenameEditField          matlab.ui.control.EditField
        TraceFilenameEditFieldLabel     matlab.ui.control.Label
        TrackerFilenameEditField        matlab.ui.control.EditField
        TrackerFilenameEditFieldLabel   matlab.ui.control.Label
        FPSEditField_Tracker            matlab.ui.control.NumericEditField
        FPSEditField_Tracker_Label      matlab.ui.control.Label
        FPSEditField_Trace              matlab.ui.control.NumericEditField
        FPSEditField_Trace_Label        matlab.ui.control.Label
        AnalyzeButton                   matlab.ui.control.Button
        TrackerVideoSaveDirectoryLabel  matlab.ui.control.Label
        TraceVideoSaveDirectoryLabel    matlab.ui.control.Label
        SaveDir_Trace                   matlab.ui.control.Button
        SaveDir_Tracker                 matlab.ui.control.Button
        SaveDirectoryTrace              matlab.ui.control.EditField
        SaveDirectoryTracker            matlab.ui.control.EditField
        SaveTraceVideoCheckBox          matlab.ui.control.CheckBox
        SaveTrackerVideoCheckBox        matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AnalysisVideoSettingsUIFigure.Units = 'normalized';
            app.AnalysisVideoSettingsUIFigure.Position(1) = 0.40;
            app.AnalysisVideoSettingsUIFigure.Position(2) = 0.40;
            app.AnalysisVideoSettingsUIFigure.Units = 'pixels';
        end

        % Button pushed function: SaveDir_Tracker
        function SaveDir_TrackerButtonPushed(app, event)
            savedir_ap = uigetdir;
            app.SaveDirectoryTracker.Value = savedir_ap;
            app.SaveDirectoryTracker.Editable = 'on';
            figure(app.AnalysisVideoSettingsUIFigure)
            app.SaveTrackerVideoCheckBox.Value = 1;
        end

        % Button pushed function: SaveDir_Trace
        function SaveDir_TraceButtonPushed(app, event)
            savedir_ap = uigetdir;
            app.SaveDirectoryTrace.Value = savedir_ap;
            app.SaveDirectoryTrace.Editable = 'on';
            figure(app.AnalysisVideoSettingsUIFigure)
            app.SaveTraceVideoCheckBox.Value = 1;
        end

        % Value changed function: SaveTrackerVideoCheckBox
        function SaveTrackerVideoCheckBoxValueChanged(app, event)
            if app.SaveTrackerVideoCheckBox.Value == 1
                saveap = app.SaveTrackerVideoCheckBox.Value;
                app.SaveDirectoryTracker.Editable = 'on';
                app.FPSEditField_Tracker_Label.Enable ='on';
                app.FPSEditField_Tracker.Enable = 'on';
                app.TrackerFilenameEditField.Enable = 'on';
                app.TrackerFilenameEditFieldLabel.Enable = 'on';
            else
                saveap = app.SaveTrackerVideoCheckBox.Value;
                app.SaveDirectoryTracker.Editable = 'off';
                app.FPSEditField_Tracker_Label.Enable ='off';
                app.FPSEditField_Tracker.Enable = 'off';
                app.TrackerFilenameEditField.Enable = 'off';
                app.TrackerFilenameEditFieldLabel.Enable = 'off';
            end
        end

        % Value changed function: SaveTraceVideoCheckBox
        function SaveTraceVideoCheckBoxValueChanged(app, event)
            if app.SaveTraceVideoCheckBox.Value == 1
                savetr = app.SaveTraceVideoCheckBox.Value;
                app.SaveDirectoryTrace.Editable = 'on';
                app.FPSEditField_Trace_Label.Enable ='on';
                app.FPSEditField_Trace.Enable = 'on';
                app.TraceFilenameEditField.Enable = 'on';
                app.TraceFilenameEditFieldLabel.Enable = 'on';
            else
                savetr = app.SaveTraceVideoCheckBox.Value;
                app.SaveDirectoryTrace.Editable = 'off';
                app.FPSEditField_Trace_Label.Enable ='off';
                app.FPSEditField_Trace.Enable = 'off';
                app.TraceFilenameEditField.Enable = 'off';
                app.TraceFilenameEditFieldLabel.Enable = 'off';
            end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            uiresume(app.AnalysisVideoSettingsUIFigure)
            app.AnalysisVideoSettingsUIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalysisVideoSettingsUIFigure = uifigure('Visible', 'off');
            app.AnalysisVideoSettingsUIFigure.Name = 'Analysis Video Settings';
            app.AnalysisVideoSettingsUIFigure.Icon = 'RodentTrackerIcon.png';
            app.SaveTrackerVideoCheckBox = uicheckbox(app.AnalysisVideoSettingsUIFigure);
            app.SaveTrackerVideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveTrackerVideoCheckBoxValueChanged, true);
            app.SaveTrackerVideoCheckBox.Tooltip = {'The tracker video comprises of the raw video overlayed by the calculated ellipse around the detected rodent.'};
            app.SaveTrackerVideoCheckBox.Text = 'Save Tracker Video?';
            app.SaveTraceVideoCheckBox = uicheckbox(app.AnalysisVideoSettingsUIFigure);
            app.SaveTraceVideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveTraceVideoCheckBoxValueChanged, true);
            app.SaveTraceVideoCheckBox.Tooltip = {'The trace video comprises of white centriod points of the detected rodent over a black background.'};
            app.SaveTraceVideoCheckBox.Text = 'Save Trace Video?';
            app.SaveDirectoryTracker = uieditfield(app.AnalysisVideoSettingsUIFigure, 'text');
            app.SaveDirectoryTracker.Editable = 'off';
            app.SaveDirectoryTrace = uieditfield(app.AnalysisVideoSettingsUIFigure, 'text');
            app.SaveDirectoryTrace.Editable = 'off';
            app.SaveDir_Tracker = uibutton(app.AnalysisVideoSettingsUIFigure, 'push');
            app.SaveDir_Tracker.ButtonPushedFcn = createCallbackFcn(app, @SaveDir_TrackerButtonPushed, true);
            app.SaveDir_Tracker.Icon = 'file_open.png';
            app.SaveDir_Tracker.Tooltip = {'This button loads the save directory of the tracker video that will be saved. The tracker video comprises of the raw video overlayed by the calculated ellipse around the detected rodent.'};
            app.SaveDir_Tracker.Text = {''; ''};
            app.SaveDir_Trace = uibutton(app.AnalysisVideoSettingsUIFigure, 'push');
            app.SaveDir_Trace.ButtonPushedFcn = createCallbackFcn(app, @SaveDir_TraceButtonPushed, true);
            app.SaveDir_Trace.Icon = 'file_open.png';
            app.SaveDir_Trace.Tooltip = {'The trace video comprises of white centriod points of the detected rodent over a black background.'};
            app.SaveDir_Trace.Text = {''; ''};
            app.TraceVideoSaveDirectoryLabel = uilabel(app.AnalysisVideoSettingsUIFigure);
            app.TraceVideoSaveDirectoryLabel.Tooltip = {'The trace video comprises of white centriod points of the detected rodent over a black background.'};
            app.TraceVideoSaveDirectoryLabel.Text = 'Trace  Video Save Directory';
            app.TrackerVideoSaveDirectoryLabel = uilabel(app.AnalysisVideoSettingsUIFigure);
            app.TrackerVideoSaveDirectoryLabel.Tooltip = {'The tracker video comprises of the raw video overlayed by the calculated ellipse around the detected rodent.'};
            app.TrackerVideoSaveDirectoryLabel.Text = 'Tracker Video Save Directory';
            app.AnalyzeButton = uibutton(app.AnalysisVideoSettingsUIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Tooltip = {'Start the analysis with the selected options.'};
            app.AnalyzeButton.Text = 'Analyze';
            app.FPSEditField_Trace_Label = uilabel(app.AnalysisVideoSettingsUIFigure);
            app.FPSEditField_Trace_Label.Enable = 'off';
            app.FPSEditField_Trace_Label.Text = 'FPS';
            app.FPSEditField_Trace = uieditfield(app.AnalysisVideoSettingsUIFigure, 'numeric');
            app.FPSEditField_Trace.Enable = 'off';
            app.FPSEditField_Trace.Value = 10;
            app.FPSEditField_Tracker_Label = uilabel(app.AnalysisVideoSettingsUIFigure);
            app.FPSEditField_Tracker_Label.Enable = 'off';
            app.FPSEditField_Tracker_Label.Text = 'FPS';
            app.FPSEditField_Tracker = uieditfield(app.AnalysisVideoSettingsUIFigure, 'numeric');
            app.FPSEditField_Tracker.Enable = 'off';
            app.FPSEditField_Tracker.Value = 10;
            app.TrackerFilenameEditFieldLabel = uilabel(app.AnalysisVideoSettingsUIFigure);
            app.TrackerFilenameEditFieldLabel.Enable = 'off';
            app.TrackerFilenameEditFieldLabel.Text = 'Filename:';
            app.TrackerFilenameEditField = uieditfield(app.AnalysisVideoSettingsUIFigure, 'text');
            app.TrackerFilenameEditField.Enable = 'off';
            app.TrackerFilenameEditField.Value = 'RodentTracker';
            app.TraceFilenameEditFieldLabel = uilabel(app.AnalysisVideoSettingsUIFigure);
            app.TraceFilenameEditFieldLabel.Enable = 'off';
            app.TraceFilenameEditFieldLabel.Text = 'Filename:';
            app.TraceFilenameEditField = uieditfield(app.AnalysisVideoSettingsUIFigure, 'text');
            app.TraceFilenameEditField.Enable = 'off';
            app.TraceFilenameEditField.Value = 'RodentTrace';
            % Show the figure after all components are created
            app.AnalysisVideoSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalysisSetup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalysisVideoSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisVideoSettingsUIFigure)
        end
    end
end