classdef RodentTracker_ROI_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        CreateNewROIMenu        matlab.ui.container.Menu
        DeleteSelectedROIsMenu  matlab.ui.container.Menu
        EditMenu                matlab.ui.container.Menu
        UITable                 matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
app.UITable.Data = [" "," "," "," "," "]';
app.UITable.Data(:,2) = ["Event 1", "Another one", "Event 3", "Different Name","More Names"]';
app.UITable.Data(:,3) = ["red", "green", "blue", "magenta","cyan"]';

app.UITable.ColumnFormat= {'logical', 'char', {'red', 'green', 'blue', 'magenta','cyan','yellow'}, 'numeric', 'numeric', 'numeric'};
app.UITable.Data(:,4:7) = rand(5,4);
s = uistyle('BackgroundColor','red');
addStyle(app.UITable,s,"cell",[1,3]);
s = uistyle('BackgroundColor','green');
addStyle(app.UITable,s,"cell",[2,3]);
s = uistyle('BackgroundColor','blue');
addStyle(app.UITable,s,"cell",[3,3]);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.UITable.Data(event.Indices(1), event.Indices(2)) = {event.EditData}
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
%             indices = event.Indices;
%             
%             if indices(2) == 3
%                 for ii = length(app.UITable.StyleConfigurations.TargetIndex):-1:1
%                     if sum(app.UITable.StyleConfigurations.TargetIndex{ii} == indices) == 2
%                         if sum(app.UITable.StyleConfigurations.Style(ii).BackgroundColor == [1 0 0])==3
%                             s = uistyle('BackgroundColor',[0 1 0]);
%                             addStyle(app.UITable,s,"cell",[indices(1),3]);
%                             break
%                         elseif sum(app.UITable.StyleConfigurations.Style(ii).BackgroundColor == [0 1 0])==3
%                             s = uistyle('BackgroundColor',[0 0 1]);
%                             addStyle(app.UITable,s,"cell",[indices(1),3]);
%                             break
%                         elseif sum(app.UITable.StyleConfigurations.Style(ii).BackgroundColor == [0 0 1])==3
%                             s = uistyle('BackgroundColor',[1 0 1]);
%                             addStyle(app.UITable,s,"cell",[indices(1),3]);
%                             break
%                         end
%                     end
%                 end
%             end
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
%             indices = app.UITable.Selection;
% 
%                         if indices(2) == 3
%                 for ii = length(app.UITable.StyleConfigurations.TargetIndex):-1:1
%                     if sum(app.UITable.StyleConfigurations.TargetIndex{ii} == indices) == 2
%                         if sum(app.UITable.StyleConfigurations.Style(ii).BackgroundColor == [1 0 0])==3
%                             s = uistyle('BackgroundColor',[0 1 0]);
%                             addStyle(app.UITable,s,"cell",[indices(1),3]);
%                             break
%                         elseif sum(app.UITable.StyleConfigurations.Style(ii).BackgroundColor == [0 1 0])==3
%                             s = uistyle('BackgroundColor',[0 0 1]);
%                             addStyle(app.UITable,s,"cell",[indices(1),3]);
%                             break
%                         elseif sum(app.UITable.StyleConfigurations.Style(ii).BackgroundColor == [0 0 1])==3
%                             s = uistyle('BackgroundColor',[1 0 1]);
%                             addStyle(app.UITable,s,"cell",[indices(1),3]);
%                             break
%                         end
%                     end
%                 end
%             end
            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            indices = event.DisplaySelection;

try
            if indices(2) == 3
                switch event.Source.Data(indices(1),indices(2)) 
                    case "red"
                    s = uistyle('BackgroundColor',[1 0 0]);
                    case "green"
                    s = uistyle('BackgroundColor',[0 1 0]);
                    case "blue"
                    s = uistyle('BackgroundColor',[0 0 1]);
                    case "magenta"
                    s = uistyle('BackgroundColor',[1 0 1]);
                    case "cyan"
                    s = uistyle('BackgroundColor',[0 1 1]);
                    case "yellow"
                    s = uistyle('BackgroundColor',[1 1 0]);
                    
                end
                addStyle(app.UITable,s,"cell",[indices(1),3]);
            end
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.CreateNewROIMenu = uimenu(app.FileMenu);
            app.CreateNewROIMenu.Text = 'Create New ROI';
            app.DeleteSelectedROIsMenu = uimenu(app.FileMenu);
            app.DeleteSelectedROIsMenu.Text = 'Delete Selected ROIs';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Select'; 'ROI'; 'Color'; 'x'; 'y'; 'width'; 'height'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true false false false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RodentTracker_ROI_Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end