classdef RodentTracker_ROI_Results_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RodentTrackerROIResultsUIFigure  matlab.ui.Figure
        FileMenu    matlab.ui.container.Menu
        SaveasMenu  matlab.ui.container.Menu
        UITable     matlab.ui.control.Table
    end

    properties (Access = private)
        Callingapp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            %Position
            app.RodentTrackerROIResultsUIFigure.Units = 'normalized';
            app.RodentTrackerROIResultsUIFigure.Position(1) = 0.3;
            app.RodentTrackerROIResultsUIFigure.Position(2) = 0.3;
            app.RodentTrackerROIResultsUIFigure.Units = 'pixels';
            app.RodentTrackerROIResultsUIFigure.Visible = 'off';
            
            app.Callingapp = Mainapp;
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            app.Callingapp.ProgressText.Text = 'Saving ROI Results...';
            filter = {'*.mat';'*.xls';'*.xlsx';'*.csv'};
            [file,path] = uiputfile(filter);
            filename = fullfile(path,file);
            [~,~,ext] = fileparts(filename);
            ROI_Results = app.RodentTrackerROIResultsUIFigure.Children(1).Data;
            if strcmp(ext,'.mat')
                save(filename,'ROI_Results')
            else
                writetable(ROI_Results,filename);
            end
            app.Callingapp.ProgressText.Text = 'ROI Results Saved';
        end

        % Close request function: RodentTrackerROIResultsUIFigure
        function RodentTrackerROIResultsUIFigureCloseRequest(app, event)
            app.RodentTrackerROIResultsUIFigure.Visible ="off";
            app.Callingapp.ROIresultsMenu.Checked = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RodentTrackerROIResultsUIFigure = uifigure('Visible', 'off');
            app.RodentTrackerROIResultsUIFigure.Name = 'Rodent Tracker: ROI Results';
            app.RodentTrackerROIResultsUIFigure.Icon = 'RodentTrackerIcon.png';
            app.RodentTrackerROIResultsUIFigure.CloseRequestFcn = createCallbackFcn(app, @RodentTrackerROIResultsUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.RodentTrackerROIResultsUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as...';
            app.UITable = uitable(app.RodentTrackerROIResultsUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.RodentTrackerROIResultsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RodentTracker_ROI_Results_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RodentTrackerROIResultsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RodentTrackerROIResultsUIFigure)
        end
    end
end