classdef RodentTracker_SAP_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RodentTrackerSAPDetectionUIFigure  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        SaveStatsMenu                 matlab.ui.container.Menu
        UItable                       matlab.ui.control.Table
        LoadGroundTruthButton         matlab.ui.control.Button
        CalculateButton               matlab.ui.control.Button
        SpeedThresholdcmsSlider       matlab.ui.control.Slider
        SpeedThresholdcmsSliderLabel  matlab.ui.control.Label
        EccentricityThresholdSliderLabel  matlab.ui.control.Label
        EccentricityThresholdSlider   matlab.ui.control.Slider
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Callingapp
        GroundTruth
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.Callingapp = MainApp;

            app.RodentTrackerSAPDetectionUIFigure.Visible = "off";
            app.RodentTrackerSAPDetectionUIFigure.Units = 'normalized';
            app.RodentTrackerSAPDetectionUIFigure.Position = [0.3 0.3 0.35 0.55];
            app.RodentTrackerSAPDetectionUIFigure.Units = 'pixel';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.Callingapp.SAPDetection = zeros(size(app.Callingapp.Eccentricity));
            app.Callingapp.SAPDetection(app.Callingapp.Eccentricity >= app.EccentricityThresholdSlider.Value)=1;
            app.Callingapp.SAPDetection(app.Callingapp.Speed > app.SpeedThresholdcmsSlider.Value)=0;

%             groundTruth = zeros(size(app.Callingapp.SAPDetection));
            [TP,TN,FP,FN,Sensitivity,Specificity,Accuracy,MCC,Fscore] = Tracking_stats(app.Callingapp.SAPDetection,app.GroundTruth);

            t = table;
            t.TP = TP;
            t.TN = TN;
            t.FP = FP;
            t.FN = FN;
            t.Sensitivity = Sensitivity;
            t.Specificity = Specificity;
            t.Accuracy = Accuracy;
            t.MCC = MCC;
            t.Fscore = Fscore;

            set(app.UItable,"Data",t)

            if (app.UIAxes.Children)<2
                bar(app.UIAxes,app.Callingapp.SAPDetection)
            else
                cla(app.UIAxes)
                bar(app.UIAxes,app.GroundTruth)
                hold(app.UIAxes,"on")
                bar(app.UIAxes,app.Callingapp.SAPDetection)
            end
        end

        % Button pushed function: LoadGroundTruthButton
        function LoadGroundTruthButtonPushed(app, event)
            [filename, folder] = uigetfile;
            data = load(fullfile(folder,filename));
            app.GroundTruth = data.GroundTruth;
            bar(app.UIAxes,app.GroundTruth)
            hold(app.UIAxes,"on")

            figure(app.RodentTrackerSAPDetectionUIFigure)
        end

        % Menu selected function: SaveStatsMenu
        function SaveStatsMenuSelected(app, event)
            app.Callingapp.ProgressText.Text = 'Saving Spreadsheet...';
            filter = {'*.mat';'*.xls';'*.xlsx';'*.csv'};
            [file,path] = uiputfile(filter);
            filename = fullfile(path,file);
            [~,~,ext] = fileparts(filename);
            Validation_stats = app.RodentTrackerSAPDetectionUIFigure.Children(1).Data;
            if strcmp(ext,'.mat')
                save(filename,'Validation_stats')
            else
                writetable(Validation_stats,filename);
            end
            app.Callingapp.ProgressText.Text = 'Validation Statistics Saved';
        end

        % Close request function: RodentTrackerSAPDetectionUIFigure
        function RodentTrackerSAPDetectionUIFigureCloseRequest(app, event)
            app.RodentTrackerSAPDetectionUIFigure.Visible ="off";
            app.Callingapp.SAPMenu.Checked = "off";
            app.Callingapp.Spreadsheet_Window.SAPMenu.Checked = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RodentTrackerSAPDetectionUIFigure = uifigure('Visible', 'off');
            app.RodentTrackerSAPDetectionUIFigure.Name = 'RodentTracker: SAP Detection';
            app.RodentTrackerSAPDetectionUIFigure.Icon = 'RodentTrackerIcon.png';
            app.RodentTrackerSAPDetectionUIFigure.CloseRequestFcn = createCallbackFcn(app, @RodentTrackerSAPDetectionUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.RodentTrackerSAPDetectionUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveStatsMenu = uimenu(app.FileMenu);
            app.SaveStatsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveStatsMenuSelected, true);
            app.SaveStatsMenu.Text = 'Save Stats..';
            app.UIAxes = uiaxes(app.RodentTrackerSAPDetectionUIFigure);
            title(app.UIAxes, 'SAP Detection')
            xlabel(app.UIAxes, 'Time (frames)')
            app.EccentricityThresholdSlider = uislider(app.RodentTrackerSAPDetectionUIFigure);
            app.EccentricityThresholdSlider.Limits = [0 1];
            app.EccentricityThresholdSlider.Value = 0.9;
            app.EccentricityThresholdSliderLabel = uilabel(app.RodentTrackerSAPDetectionUIFigure);
            app.EccentricityThresholdSliderLabel.Text = 'Eccentricity Threshold';
            app.SpeedThresholdcmsSliderLabel = uilabel(app.RodentTrackerSAPDetectionUIFigure);
            app.SpeedThresholdcmsSliderLabel.Text = 'Speed Threshold (cm/s)';
            app.SpeedThresholdcmsSlider = uislider(app.RodentTrackerSAPDetectionUIFigure);
            app.SpeedThresholdcmsSlider.Value = 12;
            app.CalculateButton = uibutton(app.RodentTrackerSAPDetectionUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.LoadGroundTruthButton = uibutton(app.RodentTrackerSAPDetectionUIFigure, 'push');
            app.LoadGroundTruthButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGroundTruthButtonPushed, true);
            app.LoadGroundTruthButton.Text = 'Load Ground Truth';
            app.UItable = uitable(app.RodentTrackerSAPDetectionUIFigure);
            app.UItable.ColumnName = {'TP'; 'TN'; 'FP'; 'FN'; 'Sensitivity'; 'Specificity'; 'Accuracy'; 'MCC'; 'Fscore'};
            app.UItable.RowName = {};
            % Show the figure after all components are created
            app.RodentTrackerSAPDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RodentTracker_SAP_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RodentTrackerSAPDetectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RodentTrackerSAPDetectionUIFigure)
        end
    end
end