classdef RodentTracker_Summary_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RodentTrackerSummaryUIFigure  matlab.ui.Figure
        FilenameLabel                 matlab.ui.control.Label
        TimeSpentLabel                matlab.ui.control.Label
        SpeedunitsLabel               matlab.ui.control.Label
        EccentricityLabel             matlab.ui.control.Label
        NumberofExitsLabel            matlab.ui.control.Label
        NumberofEntriesLabel          matlab.ui.control.Label
        TimeSpentsLabel               matlab.ui.control.Label
    end

    properties (Access = private)
        Callingapp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            %Position
            screen = get(0,'screensize');
            xres = screen(3)-screen(1);
            yres = screen(4)-screen(2);

            %Position
            app.RodentTrackerAnalysisPreviewUIFigure.Units = 'normalized';
            app.RodentTrackerAnalysisPreviewUIFigure.Position = [0.3 0.3 .3*(yres/xres) 0.3];%
            app.RodentTrackerAnalysisPreviewUIFigure.Units = 'pixels';

            app.Callingapp = Mainapp;

            app.FilenameLabel.Text = ['Filename: ' app.Callingapp.DropDown.Value];
            app.TimeSpentLabel.Text = ['Time Spent (%): ' num2str(app.Callingapp.t2.PercentageSpent_ROI)];
            app.SpeedunitsLabel.Text = ['Speed (' 'm' '/s): ' num2str(mean(app.Callingapp.t.Speed)) ' +/- ' num2str(std(app.Callingapp.t.Speed))];
            app.EccentricityLabel.Text = ['Eccentricity: ' num2str(mean(app.Callingapp.t.Eccentricity)) ' +/- ' num2str(std(app.Callingapp.t.Eccentricity))];
            app.NumberofExitsLabel.Text = ['Number of Exits: ' num2str(app.Callingapp.t2.Number_Entries)];
            app.NumberofEntriesLabel.Text = ['Number of Entries: ' num2str(app.Callingapp.t2.Number_Exits)];
            app.TimeSpentsLabel.Text = ['Time Spent (s): ' num2str(app.Callingapp.t2.TimeSpent_ROI)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RodentTrackerSummaryUIFigure = uifigure('Visible', 'off');
            app.RodentTrackerSummaryUIFigure.Name = 'Rodent Tracker: Summary';
            app.RodentTrackerSummaryUIFigure.Icon = 'RodentTrackerIcon.png';
            app.TimeSpentsLabel = uilabel(app.RodentTrackerSummaryUIFigure);
            app.TimeSpentsLabel.Text = 'Time Spent (s):';
            app.NumberofEntriesLabel = uilabel(app.RodentTrackerSummaryUIFigure);
            app.NumberofEntriesLabel.Text = 'Number of Entries:';
            app.NumberofExitsLabel = uilabel(app.RodentTrackerSummaryUIFigure);
            app.NumberofExitsLabel.Text = 'Number of Exits:';
            app.EccentricityLabel = uilabel(app.RodentTrackerSummaryUIFigure);
            app.EccentricityLabel.Text = 'Eccentricity:';
            app.SpeedunitsLabel = uilabel(app.RodentTrackerSummaryUIFigure);
            app.SpeedunitsLabel.Text = 'Speed (unit/s):';
            app.TimeSpentLabel = uilabel(app.RodentTrackerSummaryUIFigure);
            app.TimeSpentLabel.Text = 'Time Spent (%):';
            app.FilenameLabel = uilabel(app.RodentTrackerSummaryUIFigure);
            app.FilenameLabel.Text = 'Filename:';
            % Show the figure after all components are created
            app.RodentTrackerSummaryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RodentTracker_Summary_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RodentTrackerSummaryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RodentTrackerSummaryUIFigure)
        end
    end
end