classdef RodentTracker_Trace_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RodentTrackerTraceUIFigure  matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        SaveasMenu                  matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        ColorMenu                   matlab.ui.container.Menu
        ClearTraceMenu              matlab.ui.container.Menu
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Callingapp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            screen = get(0,'screensize');
            xres = screen(3)-screen(1);
            yres = screen(4)-screen(2);

            %Position
            app.RodentTrackerTraceUIFigure.Units = 'normalized';
            app.RodentTrackerTraceUIFigure.Position = [0.06+0.47*(yres/xres)+(((0.9426-0.47*(yres/xres)-(0.06+0.47*(yres/xres)))-0.47*(yres/xres))/2) 0.06 0.47*(yres/xres) 0.47];

            app.UIAxes.Units = 'normalized';
            app.UIAxes.Position = [0 0 1 1];
            axis(app.UIAxes,'ij')
            hold(app.UIAxes,"on")

            app.Callingapp = Mainapp;
        end

        % Close request function: RodentTrackerTraceUIFigure
        function RodentTrackerTraceUIFigureCloseRequest(app, event)
            app.RodentTrackerTraceUIFigure.Visible ="off";
        end

        % Menu selected function: ColorMenu
        function ColorMenuSelected(app, event)
            app.Callingapp.TraceColor = uisetcolor;

            if ~isempty(app.UIAxes.Children)
            app.UIAxes.Children(1).MarkerFaceColor = app.Callingapp.TraceColor;
            app.UIAxes.Children(1).MarkerEdgeColor = app.Callingapp.TraceColor;
            end

            figure(app.Callingapp.Video_Window.RodentTrackerAnalysisPreviewUIFigure)
            figure(app.RodentTrackerTraceUIFigure)
            figure(app.Callingapp.Spreadsheet_Window.RodentTrackerSpreadsheetUIFigure)
            figure(app.Callingapp.RodentTrackerUIFigure)
        end

        % Menu selected function: ClearTraceMenu
        function ClearTraceMenuSelected(app, event)
            delete(app.UIAxes)
            % Create UIAxes
            app.UIAxes = uiaxes(app.RodentTrackerTraceUIFigure);
            app.UIAxes.PlotBoxAspectRatio = [1 1 1];
            app.UIAxes.XLim = [0 255];
            app.UIAxes.YLim = [0 255];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Color = [0 0 0];
            app.UIAxes.TickDir = 'none';
            app.UIAxes.Position = [0 0 400 400];
            axis(app.UIAxes,'ij')
            app.RodentTrackerTraceUIFigure.Visible ='on';
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            app.Callingapp.ProgressText.Text = 'Saving Trace...';
            filter = {'*.jpg';'*.png'};
            [file,path] = uiputfile(filter);
            filename = fullfile(path,file);
            Trace_Image = getframe(app.UIAxes);
            Trace_Image = Trace_Image.cdata;
            imwrite(Trace_Image, filename);
            app.Callingapp.ProgressText.Text = 'Trace Saved';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RodentTrackerTraceUIFigure = uifigure('Visible', 'off');
            app.RodentTrackerTraceUIFigure.Name = 'Rodent Tracker: Trace';
            app.RodentTrackerTraceUIFigure.Icon = 'C:\Users\kevinh\OneDrive - MathWorks\Documents\Development\Rodent Tracking App\RodentTrackerIcon.png';
            app.RodentTrackerTraceUIFigure.CloseRequestFcn = createCallbackFcn(app, @RodentTrackerTraceUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.RodentTrackerTraceUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as...';
            app.EditMenu = uimenu(app.RodentTrackerTraceUIFigure);
            app.EditMenu.Text = 'Edit';
            app.ColorMenu = uimenu(app.EditMenu);
            app.ColorMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorMenuSelected, true);
            app.ColorMenu.Text = 'Color';
            app.ClearTraceMenu = uimenu(app.EditMenu);
            app.ClearTraceMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearTraceMenuSelected, true);
            app.ClearTraceMenu.Text = 'Clear Trace';
            app.UIAxes = uiaxes(app.RodentTrackerTraceUIFigure);
            app.UIAxes.XLim = [0 255];
            app.UIAxes.YLim = [0 255];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            % Show the figure after all components are created
            app.RodentTrackerTraceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RodentTracker_Trace_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RodentTrackerTraceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RodentTrackerTraceUIFigure)
        end
    end
end