classdef RodentTracker_Background_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BackgroundUIFigure  matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, RodentTracker)
            screen = get(0,'screensize');
            xres = screen(3)-screen(1);
            yres = screen(4)-screen(2);

            %Position
            app.BackgroundUIFigure.Units = 'normalized';
%             app.BackgroundUIFigure.Position = [0.32 0.39 0.22 0.34];
            app.BackgroundUIFigure.Position = [0.06+0.47*(yres/xres)+(((0.9426-0.47*(yres/xres)-(0.06+0.47*(yres/xres)))-0.47*(yres/xres))/2) 0.3 0.47*(yres/xres) 0.47];

            app.UIAxes.Units = 'normalized';
            app.UIAxes.Position = [0 0 1 1];
            axis(app.UIAxes,'ij')
            hold(app.UIAxes,"on")    

            app.BackgroundUIFigure.Visible ="off";
        end

        % Close request function: BackgroundUIFigure
        function BackgroundUIFigureCloseRequest(app, event)
            app.BackgroundUIFigure.Visible ="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BackgroundUIFigure = uifigure('Visible', 'off');
            app.BackgroundUIFigure.Name = 'Background';
            app.BackgroundUIFigure.Icon = 'RodentTrackerIcon.png';
            app.BackgroundUIFigure.CloseRequestFcn = createCallbackFcn(app, @BackgroundUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.BackgroundUIFigure);
            % Show the figure after all components are created
            app.BackgroundUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RodentTracker_Background_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BackgroundUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BackgroundUIFigure)
        end
    end
end