classdef RodentTracker_Spreadsheet_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RodentTrackerSpreadsheetUIFigure  matlab.ui.Figure
        FileMenu     matlab.ui.container.Menu
        SaveasMenu   matlab.ui.container.Menu
        AnalyzeMenu  matlab.ui.container.Menu
        SAPMenu      matlab.ui.container.Menu
        UITable      matlab.ui.control.Table
    end

    properties (Access = private)
        Callingapp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            screen = get(0,'screensize');
            xres = screen(3)-screen(1);
            yres = screen(4)-screen(2);

            %Position
            app.RodentTrackerSpreadsheetUIFigure.Units = 'normalized';
            app.RodentTrackerSpreadsheetUIFigure.Position = [0.9426-0.47*(yres/xres) 0.06 0.47*(yres/xres) 0.42];
            app.RodentTrackerSpreadsheetUIFigure.Units = 'pixels';
            app.RodentTrackerSpreadsheetUIFigure.Visible = 'off';

            app.Callingapp = Mainapp;
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            app.Callingapp.ProgressText.Text = 'Saving Spreadsheet...';
            filter = {'*.mat';'*.xls';'*.xlsx';'*.csv'};
            [file,path] = uiputfile(filter);
            filename = fullfile(path,file);
            [~,~,ext] = fileparts(filename);
            t = app.RodentTrackerSpreadsheetUIFigure.Children(1).Data;
            if strcmp(ext,'.mat')
                save(filename,'t')
            else
                writetable(t,filename);
            end
            app.Callingapp.ProgressText.Text = 'Spreadsheet Saved';
        end

        % Close request function: RodentTrackerSpreadsheetUIFigure
        function RodentTrackerSpreadsheetUIFigureCloseRequest(app, event)
            app.RodentTrackerSpreadsheetUIFigure.Visible ="off";
            app.Callingapp.MainSpreadsheetMenu.Checked = "off";
        end

        % Menu selected function: SAPMenu
        function SAPMenuSelected(app, event)
            app.Callingapp.SAP_Window.RodentTrackerSAPDetectionUIFigure.Visible = "on";

            if app.Callingapp.SAP_Window.RodentTrackerSAPDetectionUIFigure.Visible == "on" 
                app.Callingapp.SAP_Window.RodentTrackerSAPDetectionUIFigure.Visible = "off";
                app.SAPMenu.Checked = "off";
                app.Callingapp.SAPMenu.Checked = "off";
            else
                app.Callingapp.SAP_Window.RodentTrackerSAPDetectionUIFigure.Visible = "on";
                app.SAPMenu.Checked = "on";
                app.Callingapp.SAPMenu.Checked = "on";
                figure(app.Callingapp.SAP_Window.RodentTrackerSAPDetectionUIFigure)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RodentTrackerSpreadsheetUIFigure = uifigure('Visible', 'off');
            app.RodentTrackerSpreadsheetUIFigure.Name = 'Rodent Tracker: Spreadsheet';
            app.RodentTrackerSpreadsheetUIFigure.Icon = 'RodentTrackerIcon.png';
            app.RodentTrackerSpreadsheetUIFigure.CloseRequestFcn = createCallbackFcn(app, @RodentTrackerSpreadsheetUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.RodentTrackerSpreadsheetUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as...';
            app.AnalyzeMenu = uimenu(app.RodentTrackerSpreadsheetUIFigure);
            app.AnalyzeMenu.Text = 'Analyze';
            app.SAPMenu = uimenu(app.AnalyzeMenu);
            app.SAPMenu.MenuSelectedFcn = createCallbackFcn(app, @SAPMenuSelected, true);
            app.SAPMenu.Text = 'SAP';
            app.UITable = uitable(app.RodentTrackerSpreadsheetUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.RodentTrackerSpreadsheetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RodentTracker_Spreadsheet_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RodentTrackerSpreadsheetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RodentTrackerSpreadsheetUIFigure)
        end
    end
end